classdef inspectDataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        FeatureDropDown       matlab.ui.control.DropDown
        FeatureDropDownLabel  matlab.ui.control.Label
        DatasetDropDown       matlab.ui.control.DropDown
        DatasetDropDownLabel  matlab.ui.control.Label
        FilesListBox          matlab.ui.control.ListBox
        RightPanel            matlab.ui.container.Panel
        TimePanel             matlab.ui.container.Panel
        GridLayout2_2         matlab.ui.container.GridLayout
        FreqPanel             matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        FeaturePlotAxes       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        files          % Table of selected files
        featureTbl     % FeatureTable from pipeline
        featureNames   % Array of feature names
        sigTable       % Signal table
        labelOrder     = ["healthy", "system misalignment", "misalignment", "faulty bearing"]
        signalType
    end
    
    methods (Access = private)
        
        function loadDataset(app, datasetName)
            app.files = metadata.selectPreset(metadata.collect('DataSet'), string(datasetName));
            pipeline_opt.windowOverlap = 0;
            [~, ~, app.featureTbl, app.featureNames] = features.pipeline(app.files, pipeline_opt); 

            % Signal table
            app.sigTable = preprocess.buildSignalTable(app.files, 1, 0, @io.readCurrent, @io.readVibro);

            % Parse featureNames & filePaths
            app.FeatureDropDown.Items = app.featureNames;
            app.FeatureDropDown.Value = app.featureNames(1);

            app.FilesListBox.Items = "";

            % Trigger update plots
            updatePlots(app);
        end
        
        function updatePlots(app)
            % --- Settings
            featureToInspect = app.FeatureDropDown.Value;
            selectedFilePath = app.FilesListBox.Value;

            if app.files.SignalType == 'Vibration'
                fileVector  = string(app.featureTbl.metaVibro.FilePath);
            else
                fileVector  = string(app.featureTbl.metaCurrent.FilePath);
            end

            % === Plot Feature Landscape ===
            % Only valid labels
            featureVector = app.featureTbl.(featureToInspect);
            labelVector = string(app.featureTbl.label);
            

            isValid = ismember(labelVector, app.labelOrder);
            featureVector = featureVector(isValid);
            labelVector = labelVector(isValid);
            fileVector = fileVector(isValid);

            % Sort features by label order
            labelNumeric = zeros(length(labelVector), 1);
            for i = 1:length(app.labelOrder)
                labelNumeric(labelVector == app.labelOrder(i)) = i;
            end
            [~, sortIdx] = sort(labelNumeric);
            sf = featureVector(sortIdx);
            sfp = fileVector(sortIdx);
            sln = labelNumeric(sortIdx);

            % Plot feature
            cla(app.FeaturePlotAxes);
            plot(app.FeaturePlotAxes, sf, '-o');
            title(app.FeaturePlotAxes, "Feature: " + featureToInspect);
            ylabel(app.FeaturePlotAxes, featureToInspect);
            xlabel(app.FeaturePlotAxes, "Sorted Windows");
            grid(app.FeaturePlotAxes, 'on');
            hold(app.FeaturePlotAxes, 'on');

            % Label & file transitions
            labelChangeIdx = find(diff(sln) ~= 0);
            for k = 1:length(labelChangeIdx)
                xline(app.FeaturePlotAxes, labelChangeIdx(k)+0.5, 'r--', 'LineWidth', 2);
            end
            fileChangeIdx = find(~strcmp(sfp(1:end-1), sfp(2:end)));
            fileTransitionFiles = sfp(fileChangeIdx + 1);  % +1 because change happens at "next" sample
            fileTransitionFiles = [sfp(1); fileTransitionFiles];
            
            app.FilesListBox.Items = fileTransitionFiles;
            if (isempty(selectedFilePath))
                selectedFilePath = app.FilesListBox.Items(1);
            end

            for i = 1:length(fileChangeIdx)
                xline(app.FeaturePlotAxes, fileChangeIdx(i)+0.5, 'Color', [0 0.9 0], 'LineWidth', 1.2);
            end
            hold(app.FeaturePlotAxes, 'off');

            import matlab.ui.container.*

            % Clean content of time/freq panels
            delete(app.TimePanel.Children);
            delete(app.FreqPanel.Children);

            %timeLayout = uigridlayout(app.TimePanel, [1 1]);
            %freqLayout = uigridlayout(app.FreqPanel, [1 1]);

            subSigTable = preprocess.findRowsByFilePath(app.sigTable, string(selectedFilePath));
            opts.titleStr = string(selectedFilePath);
            opts.parent = app.TimePanel;
            
            if (contains(string(selectedFilePath), "LTR11"))
                sigTypeStr = 'currents';
            else
                sigTypeStr = 'vibro';
            end

            opts.signalType = sigTypeStr;
            viz.windowTimeDomain(subSigTable, opts);
            opts.parent = app.FreqPanel;
            viz.windowFreqDomain(subSigTable, opts);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load available dataset names
            datasetList = arrayfun(@(i) ['dataset' num2str(i)], 1:25, 'UniformOutput', false);
            app.DatasetDropDown.Items = datasetList;
            app.DatasetDropDown.Value = datasetList(1);

            % Load initial dataset
            loadDataset(app, datasetList(1));
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            dataset = app.DatasetDropDown.Value;
            loadDataset(app, dataset);
        end

        % Value changed function: FeatureDropDown
        function FeatureDropDownValueChanged(app, event)
            updatePlots(app);
        end

        % Double-clicked callback: FilesListBox
        function FilesListBoxDoubleClicked(app, event)
            updatePlots(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {564, 564};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {308, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FilesListBox = uilistbox(app.LeftPanel);
            app.FilesListBox.Items = {};
            app.FilesListBox.DoubleClickedFcn = createCallbackFcn(app, @FilesListBoxDoubleClicked, true);
            app.FilesListBox.Value = {};
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.LeftPanel);
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.FeatureDropDownLabel = uilabel(app.LeftPanel);
            app.FeatureDropDownLabel.Text = 'Feature';
            app.FeatureDropDown = uidropdown(app.LeftPanel);
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureDropDownValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.FeaturePlotAxes = uiaxes(app.RightPanel);
            title(app.FeaturePlotAxes, 'Title')
            xlabel(app.FeaturePlotAxes, 'X')
            ylabel(app.FeaturePlotAxes, 'Y')
            zlabel(app.FeaturePlotAxes, 'Z')
            app.FreqPanel = uipanel(app.RightPanel);
            app.FreqPanel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.FreqPanel);
            app.TimePanel = uipanel(app.RightPanel);
            app.TimePanel.Title = 'Panel2';
            app.GridLayout2_2 = uigridlayout(app.TimePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inspectDataset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end